classdef Topic1App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        Lamp                       matlab.ui.control.Lamp
        phaseLagLead               matlab.ui.control.DropDown
        freqDropDown               matlab.ui.control.DropDown
        degreeLabel                matlab.ui.control.Label
        phaseEditField             matlab.ui.control.NumericEditField
        phaseEditFieldLabel        matlab.ui.control.Label
        unittime                   matlab.ui.control.DropDown
        DCoffset                   matlab.ui.control.NumericEditField
        DCoffsetEditFieldLabel_3   matlab.ui.control.Label
        amplitude                  matlab.ui.control.NumericEditField
        amplitudeEditFieldLabel_3  matlab.ui.control.Label
        frequency                  matlab.ui.control.NumericEditField
        frequencyEditFieldLabel_3  matlab.ui.control.Label
        timediv                    matlab.ui.control.NumericEditField
        timedivLabel               matlab.ui.control.Label
        unitvolt                   matlab.ui.control.DropDown
        voltdiv                    matlab.ui.control.NumericEditField
        voltdivLabel               matlab.ui.control.Label
        VLabel_2                   matlab.ui.control.Label
        VLabel                     matlab.ui.control.Label
        FunctionGeneratorLabel     matlab.ui.control.Label
        powerSwitch                matlab.ui.control.Switch
        powerSwitchLabel           matlab.ui.control.Label
        functionDropDown           matlab.ui.control.DropDown
        functionDropDownLabel      matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    methods (Access = public)
        
        function [utime] = unitvalue(app)
                uputime = app.unittime.Value;
                if uputime == "Gs"
                    utime = 10^9;
                end
                if uputime == "Ms"
                    utime = 10^6;
                end
                if uputime == "ks"
                    utime = 10^3;
                end
                if uputime == "s"
                    utime = 10^0;
                end
                if uputime == "ms"
                    utime = 10^(-3);
                end
                if uputime == "us"
                    utime = 10^(-6);
                end
                if uputime == "ns"
                    utime = 10^(-9);
                end
                if uputime == "ps"
                    utime = 10^(-12);
                end
        end
        
        function [vidi] = voltdivValue(app)
                vd = app.unitvolt.Value;
                if vd == "GV"
                    vidi = 10^9;
                end
                if vd == "MV"
                    vidi = 10^6;
                end
                if vd == "kV"
                    vidi = 10^3;
                end
                if vd == "V"
                    vidi = 10^0;
                end
                if vd == "mV"
                    vidi = 10^(-3);
                end
                if vd == "uV"
                    vidi = 10^(-6);
                end
                if vd == "nV"
                    vidi = 10^(-9);
                end
                if vd == "pV"
                    vidi = 10^(-12);
                end
        end
        function [fq] = freqValue(app)
                f = app.freqDropDown.Value;
                if f == "GHz"
                    fq = 10^9;
                end
                if f == "MHz"
                    fq = 10^6;
                end
                if f == "kHz"
                    fq = 10^3;
                end
                if f == "Hz"
                    fq = 10^0;
                end
                if f == "mHz"
                    fq = 10^(-3);
                end
                if f == "uHz"
                    fq = 10^(-6);
                end
                if f == "nHz"
                    fq = 10^(-9);
                end
                if f == "pHz"
                   fq = 10^(-12);
                end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: powerSwitch
        function powerSwitchValueChanged(app, event)
            % Check power
            pw = string(app.powerSwitch.Value);
            
            if pw == "Off"
                cla(app.UIAxes);
                app.phaseLagLead.Value = 'Lagging';
                app.Lamp.Color = 'r';
            end
            
            if pw == "On"
                app.Lamp.Color = 'g';
                
                % unit time/div dropdown
           
                ut = unitvalue(app);
                tidi = app.timediv.Value * ut;
                Amp =  app.amplitude.Value;
                offset = app.DCoffset.Value;
                
                % unit frequency dropdown
                fre = freqValue(app);
                frq = app.frequency.Value*fre;
                T = 1/frq;
                
                omega = 2*pi/T;
                %N = 2^10;
                K = 100;
                
                % function dropdown
                ft = app.functionDropDown.Value;
                
                % phase degree
                ps = app.phaseEditField.Value;
                ps = ps*(pi/180);
                LagLead = app.phaseLagLead.Value;
                if LagLead == "Lagging"
                    ps = ps*(-1);
                end
                
                % volt/div
                vod = voltdivValue(app);
                voltd = app.voltdiv.Value*vod;
                app.UIAxes.YLim = [(-1)*voltd voltd];
                
                if ft == "sine"
                    t = 0:1/(2^20):tidi;
                    y = Amp*sin(omega*t+ps) + offset;
                    plot(app.UIAxes, t, y);
                end
                
                if ft == "square"
                    y = 0;
                    t = 0:1/(2^20):tidi;
                    for k=1:2:K
                        y = y + ((Amp*4/pi)*sin((k*omega*t)+(ps*k))/k);
                        plot(app.UIAxes, t, y + offset);
                    end
                end
                
                if ft == "triangular"
                    y = Amp/2;
                    t = 0:1/(2^20):tidi;
                    for k=1:2:K
                        y  = y + ((-4*Amp/(k*pi)^2)*cos((k*omega*t)+(ps*k)));
                        plot(app.UIAxes, t, y + offset);
                    end
                end
                
                if ft == "sawtooth"
                    y = Amp/2;
                    t = 0:1/(2^20):tidi;
                    for k=1:1:K
                        y  = y + (-Amp/(k*pi)*sin((k*omega*t)+(ps*k)));
                        %y = y + (-Amp/(pi*k))*sin((k*omega*t)+(ps*k));
                        plot(app.UIAxes, t, y + offset);
                    end
                end
                
                if ft == "Full-wave rectified"
                    y = (2*Amp)/pi;
                    t = 0:1/(2^20):tidi;
                    for k=1:1:K
                        y  = y + (-4*Amp/(((4*k^2)-1)*pi))*cos((k*omega*t)+(ps*k));
                        plot(app.UIAxes, t, y + offset);
                    end
                end
            end
        end

        % Value changed function: frequency
        function frequencyValueChanged(app, event)
            %f = app.frequency.Value;
            powerSwitchValueChanged(app, event);  
        end

        % Value changed function: functionDropDown
        function functionDropDownValueChanged(app, event)
            %value = app.functionDropDown.Value;
            powerSwitchValueChanged(app, event);
        end

        % Value changed function: amplitude
        function amplitudeValueChanged(app, event)
            %value = app.amplitude.Value;
            powerSwitchValueChanged(app, event);
        end

        % Value changed function: DCoffset
        function DCoffsetValueChanged(app, event)
            %value = app.DCoffset.Value;
            powerSwitchValueChanged(app, event);
        end

        % Value changed function: phaseEditField
        function phaseEditFieldValueChanged(app, event)
            %value = app.phaseEditField.Value;
            powerSwitchValueChanged(app, event);
        end

        % Value changed function: timediv
        function timedivValueChanged(app, event)
            %value = app.timediv.Value;
            powerSwitchValueChanged(app, event);
        end

        % Value changed function: unittime
        function unittimeValueChanged(app, event)
            %value = app.unittime.Value;
            powerSwitchValueChanged(app, event);
        end

        % Value changed function: voltdiv
        function voltdivValueChanged(app, event)
            %value = app.voltdiv.Value;
            powerSwitchValueChanged(app, event);
        end

        % Value changed function: unitvolt
        function unitvoltValueChanged(app, event)
            %value = app.unitvolt.Value;
            powerSwitchValueChanged(app, event);
        end

        % Value changed function: freqDropDown
        function freqDropDownValueChanged(app, event)
            %value = app.freqDropDown.Value;
            powerSwitchValueChanged(app, event);
        end

        % Value changed function: phaseLagLead
        function phaseLagLeadValueChanged(app, event)
            %value = app.phaseLagLead.Value;
            powerSwitchValueChanged(app, event);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateufreq(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {460, 460};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {298, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateufreq, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.functionDropDownLabel = uilabel(app.LeftPanel);
            app.functionDropDownLabel.Text = 'function';
            app.functionDropDown = uidropdown(app.LeftPanel);
            app.functionDropDown.Items = {'sine', 'square', 'triangular', 'sawtooth', 'Full-wave rectified'};
            app.functionDropDown.ValueChangedFcn = createCallbackFcn(app, @functionDropDownValueChanged, true);
            app.functionDropDown.Value = 'sine';
            app.powerSwitchLabel = uilabel(app.LeftPanel);
            app.powerSwitchLabel.Text = 'power';
            app.powerSwitch = uiswitch(app.LeftPanel, 'slider');
            app.powerSwitch.ValueChangedFcn = createCallbackFcn(app, @powerSwitchValueChanged, true);
            app.FunctionGeneratorLabel = uilabel(app.LeftPanel);
            app.FunctionGeneratorLabel.Text = 'Function Generator';
            app.VLabel = uilabel(app.LeftPanel);
            app.VLabel.Text = 'V';
            app.VLabel_2 = uilabel(app.LeftPanel);
            app.VLabel_2.Text = 'V';
            app.voltdivLabel = uilabel(app.LeftPanel);
            app.voltdivLabel.Text = 'volt/div';
            app.voltdiv = uieditfield(app.LeftPanel, 'numeric');
            app.voltdiv.Limits = [1 1000];
            app.voltdiv.ValueChangedFcn = createCallbackFcn(app, @voltdivValueChanged, true);
            app.voltdiv.Value = 1;
            app.unitvolt = uidropdown(app.LeftPanel);
            app.unitvolt.Items = {'GV', 'MV', 'kV', 'V', 'mV', 'uV', 'nV', 'pV'};
            app.unitvolt.ValueChangedFcn = createCallbackFcn(app, @unitvoltValueChanged, true);
            app.unitvolt.Value = 'V';
            app.timedivLabel = uilabel(app.LeftPanel);
            app.timedivLabel.Text = 'time/div';
            app.timediv = uieditfield(app.LeftPanel, 'numeric');
            app.timediv.Limits = [1 1000];
            app.timediv.ValueChangedFcn = createCallbackFcn(app, @timedivValueChanged, true);
            app.timediv.Value = 1;
            app.frequencyEditFieldLabel_3 = uilabel(app.LeftPanel);
            app.frequencyEditFieldLabel_3.Text = 'frequency';
            app.frequency = uieditfield(app.LeftPanel, 'numeric');
            app.frequency.Limits = [1 Inf];
            app.frequency.ValueChangedFcn = createCallbackFcn(app, @frequencyValueChanged, true);
            app.frequency.Value = 1000;
            app.amplitudeEditFieldLabel_3 = uilabel(app.LeftPanel);
            app.amplitudeEditFieldLabel_3.Text = 'amplitude';
            app.amplitude = uieditfield(app.LeftPanel, 'numeric');
            app.amplitude.Limits = [0 Inf];
            app.amplitude.ValueChangedFcn = createCallbackFcn(app, @amplitudeValueChanged, true);
            app.amplitude.Value = 1;
            app.DCoffsetEditFieldLabel_3 = uilabel(app.LeftPanel);
            app.DCoffsetEditFieldLabel_3.Text = 'DC offset';
            app.DCoffset = uieditfield(app.LeftPanel, 'numeric');
            app.DCoffset.ValueChangedFcn = createCallbackFcn(app, @DCoffsetValueChanged, true);
            app.unittime = uidropdown(app.LeftPanel);
            app.unittime.Items = {'Gs', 'Ms', 'ks', 's', 'ms', 'us', 'ns', 'ps'};
            app.unittime.ValueChangedFcn = createCallbackFcn(app, @unittimeValueChanged, true);
            app.unittime.Value = 'ms';
            app.phaseEditFieldLabel = uilabel(app.LeftPanel);
            app.phaseEditFieldLabel.Text = 'phase';
            app.phaseEditField = uieditfield(app.LeftPanel, 'numeric');
            app.phaseEditField.ValueChangedFcn = createCallbackFcn(app, @phaseEditFieldValueChanged, true);
            app.degreeLabel = uilabel(app.LeftPanel);
            app.degreeLabel.Text = 'degree';
            app.freqDropDown = uidropdown(app.LeftPanel);
            app.freqDropDown.Items = {'GHz', 'MHz', 'kHz', 'Hz', 'mHz', 'uHz', 'nHz', 'pHz'};
            app.freqDropDown.ValueChangedFcn = createCallbackFcn(app, @freqDropDownValueChanged, true);
            app.freqDropDown.Value = 'Hz';
            app.phaseLagLead = uidropdown(app.LeftPanel);
            app.phaseLagLead.Items = {'Lagging', 'Leading'};
            app.phaseLagLead.ValueChangedFcn = createCallbackFcn(app, @phaseLagLeadValueChanged, true);
            app.phaseLagLead.Value = 'Lagging';
            app.Lamp = uilamp(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'graph')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Voltage (V)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Topic1App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end